classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        TabGroup              matlab.ui.container.TabGroup
        Tab                   matlab.ui.container.Tab
        EnableRealTimeButton  matlab.ui.control.Button
        UIAxes                matlab.ui.control.UIAxes
        Tab2                  matlab.ui.container.Tab
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: EnableRealTimeButton
        function EnableRealTimeButtonPushed(app, event)
            % Clear plot
            cla(app.UIAxes) 
            
            %f = parfeval(backgroundPool,@test,1,5,6)
            p = parpool("local",1);
            f = parfeval(p,@position_control_main,1,5,6);
            pause(1);
            filename = fullfile(tempdir, 'position_control_memshare.dat');
            
            while(~exist(filename, 'file'))
                continue
            end
            disp("Memory share file found")
            % Memory map the file.
            m = memmapfile(filename, 'Writable', true, 'Format', 'double');
            ahrs = [];
            mocap = [];
            i=1;
            while(1)
                ahrs(i) = m.Data(1);
                mocap(i) = m.Data(2);
                hold(app.UIAxes,"on");
                plot(app.UIAxes, ahrs, "b");
                plot(app.UIAxes, mocap, "r");
                if(f.State == "finished")
                    disp("Position Control Ended")
                    break;
                end
                i=i+1;
                pause(0.01)
            end
            
            delete(gcp('nocreate'))
            clear;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.UIAxes = uiaxes(app.Tab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.EnableRealTimeButton = uibutton(app.Tab, 'push');
            app.EnableRealTimeButton.ButtonPushedFcn = createCallbackFcn(app, @EnableRealTimeButtonPushed, true);
            app.EnableRealTimeButton.Text = 'Enable Real-Time';
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end